classdef CiftiStorm < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        FileMenu                matlab.ui.container.Menu
        NewprocessMenu          matlab.ui.container.Menu
        DatasetMenu_3           matlab.ui.container.Menu
        ListMenu                matlab.ui.container.Menu
        LoadMenu                matlab.ui.container.Menu
        DeleteMenu              matlab.ui.container.Menu
        ExitMenu                matlab.ui.container.Menu
        ToolsMenu               matlab.ui.container.Menu
        ViewMenu                matlab.ui.container.Menu
        HelpMenu                matlab.ui.container.Menu
        UpdateMenu              matlab.ui.container.Menu
        UsermanualMenu          matlab.ui.container.Menu
        AboutMenu               matlab.ui.container.Menu
        Toolbar                 matlab.ui.container.Toolbar
        PushToolNewProcess      matlab.ui.container.toolbar.PushTool
        PushToolDefault_Params  matlab.ui.container.toolbar.PushTool
        PushToolListDataset     matlab.ui.container.toolbar.PushTool
        PushToolExplorerDS      matlab.ui.container.toolbar.PushTool
        PanelBreadcrumbs        matlab.ui.container.Panel
        TabGroup                matlab.ui.container.TabGroup
        InputTab                matlab.ui.container.Tab
        TreeInput               matlab.ui.container.Tree
        OutputTab               matlab.ui.container.Tab
        TreeOutput              matlab.ui.container.Tree
    end

    
    properties (Access = private)
        cfs_properties % Description
    end
    
    methods (Access = private)
        
        function ExploreFolder(app,nodeData)
            app.cfs_properties = get_properties();
            app.TreeInput.Children.delete;
            if(ismember(nodeData.name,{'..'}))
                rootDir = fileparts(nodeData.folder);                              
                dirName = strsplit(rootDir,filesep);
                dirName = dirName{end};                
            else
                rootDir = fullfile(nodeData.folder,nodeData.name);
                dirName = nodeData.name;
            end            
            parent = uitreenode(app.TreeInput,'Text',dirName);
            parent.Icon = 'guide/images/folder.png';
            file_list = dir(rootDir);        
            file_list(ismember({file_list.name},{'.'})) = [];
            for i=1:length(file_list)
                fileStruct = file_list(i);
                if(fileStruct.isdir)
                    if(isequal(fileStruct.name,'..'))
                        icon_file = 'guide/images/folder_parent.png';
                    else
                        icon_file = 'guide/images/folder.png';
                    end
                    nodes(i)=uitreenode(parent, 'Text', file_list(i).name);
                    nodes(i).Icon = icon_file;
                    nodes(i).NodeData = fileStruct;
                    % Contex menu
                    cm = uicontextmenu(app.UIFigure);
                    m1 = uimenu(cm,"Text","Define as Anatomy folder","MenuSelectedFcn",{@DefineProperties,'Anatomy',nodes(i).NodeData});
                    m2 = uimenu(cm,"Text","Define as Non-Brain folder","MenuSelectedFcn",{@DefineProperties,'Non-brain',nodes(i).NodeData});
                    nodes(i).ContextMenu = cm;
                end
            end
            for i=1:length(file_list)
                fileStruct = file_list(i);
                if(~fileStruct.isdir)
                    nodes(i)=uitreenode(parent, 'Text', file_list(i).name);      
                    nodes(i).Icon = 'guide/images/file.jpeg';
                end
            end
            expand(parent);
        end
        
        function Breadcrumbs(app,path)
            pathParts = strsplit(path,filesep);
            app.PanelBreadcrumbs.Children = [];
            
            uilabel(app.PanelBreadcrumbs,Text=filesep,Position=[5 5 5 25],FontSize=14, FontWeight="bold");
            for i=2:length(pathParts)
                if(i < length(pathParts))
                    text = strcat(pathParts(i)," ",filesep," ");                    
                else
                    text = pathParts(i); 
                end
                position = app.PanelBreadcrumbs.Children(1).Position;
                left = position(1)+position(3)+5;
                label = uilabel(app.PanelBreadcrumbs, ...
                    Text=text, ...
                    Position=[left 5 7.5*strlength(text) 25], ...
                    FontSize=14, ...
                    FontWeight="bold", ...
                    HorizontalAlignment="left");  
                
            end
        end
        
    end
    
    methods (Access = public)
        
        function DefineProperties(app,event,action,clasif,nodeData)
            switch clasif
                case 'Anatomy'
                    base_path = fullfile(nodeData.folder,nodeData.name); 
                    app.cfs_properties.anatomy_params.anatomy_type.type_list{2}.base_path = base_path;  
                    saveJSON(app.bcv_properties.general_params,'cfs_properties/general_params.json');
                    h = matlab.desktop.editor.openDocument(fullfile(pwd,'cfs_properties/general_params.json'));
                    h.smartIndentContents
                    h.save
                    h.close
                case 'Non-brain'
                    base_path = fullfile(nodeData.folder,nodeData.name); 
                    app.cfs_properties.anatomy_params.common_params.non_brain_surfaces.base_path = base_path; 
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            userDir = getUserDir();
            Breadcrumbs(app,userDir)
            parent = uitreenode(app.TreeInput,'Text',userDir);
            parent.Icon = 'guide/images/folder.png';
            file_list = dir(userDir);
            file_list(ismember({file_list.name},{'.'})) = [];
            for i=1:length(file_list)
                fileStruct = file_list(i);
                if(fileStruct.isdir)
                    if(isequal(fileStruct.name,'..'))
                        icon_file = 'guide/images/folder_parent.png';
                    else
                        icon_file = 'guide/images/folder.png';
                    end
                    nodes(i)=uitreenode(parent, 'Text', file_list(i).name);  
                    nodes(i).Icon = icon_file;
                    nodes(i).NodeData = fileStruct;
                    % Context menu
                    cm = uicontextmenu(app.UIFigure);
                    m1 = uimenu(cm,"Text","Define as Anatomy folder","MenuSelectedFcn",{@app.DefineProperties,'Anatomy',fileStruct});
                    m2 = uimenu(cm,"Text","Define as Non-Brain folder","MenuSelectedFcn",{@app.DefineProperties,'Non-brain',fileStruct});
                    nodes(i).ContextMenu = cm;
                end
            end
            for i=1:length(file_list)
                fileStruct = file_list(i);
                if(~fileStruct.isdir)
                    nodes(i)=uitreenode(parent, 'Text', file_list(i).name);      
                    nodes(i).Icon = 'guide/images/file.jpeg';
                end
            end
            expand(parent);
        end

        % Menu selected function: ExitMenu
        function ExitMenuSelected(app, event)
            delete(app);
        end

        % Menu selected function: NewprocessMenu
        function NewprocessMenuSelected(app, event)
            obj_configure = Params_configuration();
        end

        % Double-clicked callback: TreeInput
        function TreeInputDoubleClicked(app, event)
            node = event.InteractionInformation.Node;
            if(node.NodeData.isdir)
                ExploreFolder(app,node.NodeData);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.NewprocessMenu = uimenu(app.FileMenu);
            app.NewprocessMenu.MenuSelectedFcn = createCallbackFcn(app, @NewprocessMenuSelected, true);
            app.NewprocessMenu.Text = 'New process';
            app.DatasetMenu_3 = uimenu(app.FileMenu);
            app.DatasetMenu_3.Text = 'Dataset';
            app.ListMenu = uimenu(app.DatasetMenu_3);
            app.ListMenu.Text = 'List';
            app.LoadMenu = uimenu(app.DatasetMenu_3);
            app.LoadMenu.Text = 'Load';
            app.DeleteMenu = uimenu(app.DatasetMenu_3);
            app.DeleteMenu.Text = 'Delete';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitMenuSelected, true);
            app.ExitMenu.Text = 'Exit';
            app.ToolsMenu = uimenu(app.UIFigure);
            app.ToolsMenu.Text = 'Tools';
            app.ViewMenu = uimenu(app.UIFigure);
            app.ViewMenu.Text = 'View';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.UpdateMenu = uimenu(app.HelpMenu);
            app.UpdateMenu.Text = 'Update';
            app.UsermanualMenu = uimenu(app.HelpMenu);
            app.UsermanualMenu.Text = 'User manual';
            app.AboutMenu = uimenu(app.HelpMenu);
            app.AboutMenu.Text = 'About';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.PushToolNewProcess = uipushtool(app.Toolbar);
            app.PushToolNewProcess.Icon = fullfile(pathToMLAPP, 'images', 'new_process.jpeg');
            app.PushToolDefault_Params = uipushtool(app.Toolbar);
            app.PushToolDefault_Params.Icon = fullfile(pathToMLAPP, 'images', 'default.png');
            app.PushToolListDataset = uipushtool(app.Toolbar);
            app.PushToolListDataset.Icon = fullfile(pathToMLAPP, 'images', 'list_dataset.png');
            app.PushToolExplorerDS = uipushtool(app.Toolbar);
            app.PushToolExplorerDS.Icon = fullfile(pathToMLAPP, 'images', 'explore.png');
            app.TabGroup = uitabgroup(app.UIFigure);
            app.InputTab = uitab(app.TabGroup);
            app.InputTab.Title = 'Input';
            app.TreeInput = uitree(app.InputTab);
            app.TreeInput.DoubleClickedFcn = createCallbackFcn(app, @TreeInputDoubleClicked, true);
            app.OutputTab = uitab(app.TabGroup);
            app.OutputTab.Title = 'Output';
            app.TreeOutput = uitree(app.OutputTab);
            app.PanelBreadcrumbs = uipanel(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CiftiStorm

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end